classdef videoapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlyViewerUIFigure            matlab.ui.Figure
        StartVideoAcquisitionButton  matlab.ui.control.Button
        SavePostureButton            matlab.ui.control.Button
        AssignPostureButton          matlab.ui.control.Button
        OverlayPostureSwitchLabel    matlab.ui.control.Label
        OverlayPostureSwitch         matlab.ui.control.Switch
        StopVideoAcquisitionButton   matlab.ui.control.Button
        StartPreviewButton           matlab.ui.control.Button
        StopPreviewButton            matlab.ui.control.Button
        FlyViewer2019TurnerLabHHMILabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        FlyRepIDEditFieldLabel       matlab.ui.control.Label
        FlyRepIDEditField            matlab.ui.control.EditField
        CloseButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        vid = videoinput('pointgrey',1,'F7_Raw8_1280x1024_Mode0');;
        pstrfilename = '';
        logfilename = '';
        previewfigure;
        overlayfigure;
        previewactive=false;
        acquisitionactive=false;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: AssignPostureButton
        function AssignPostureButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            app.pstrfilename = fullfile(folder, baseName);
            app.AssignPostureButton.Text = 'Assigned';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.vid)
            close all force
        end

        % Close request function: FlyViewerUIFigure
        function FlyViewerUIFigureCloseRequest(app, event)
            %delete(app);
        end

        % Value changed function: OverlayPostureSwitch
        function OverlayPostureSwitchValueChanged(app, event)
            postr = string(app.OverlayPostureSwitch.Value);
            if postr == 'On'
                if app.pstrfilename == ""
                    app.Label.Text = 'ERROR: Assign Posture File.';
                    pause(2)
                    app.OverlayPostureSwitch.Value = 'Off';
                    app.Label.Text = '';
                else
                    load(app.pstrfilename,'frame');
                    
                    app.Label.Text = 'Loaded Posture File.';
                    pause(2)
                    app.Label.Text = '';
                    
                    app.overlayfigure = figure('numbertitle', 'off', ...
                                           'Name','Overlay (DO NOT PRESS CLOSE BUTTTON)', ...
                                           'MenuBar', 'none', ...
                                           'ToolBar', 'none');
                    figure(app.overlayfigure);
                    set(app.overlayfigure,'CloseRequestFcn',[]);
                    axes_overlay = axes('Parent',app.overlayfigure);
                    
                    while strcmp(app.OverlayPostureSwitch.Value,'On') 
                        try
                            %figure(app.overlayfigure);
                            im = getsnapshot(app.vid);
                            normim = uint8(255*mat2gray(im));
                            normframe = uint8(255*mat2gray(frame));
                            imshow(imabsdiff(normim,normframe),'Parent',axes_overlay);
                            drawnow;
                        catch
                            warning('Error Occured');
                        end
                    end
                    
                end
            elseif postr == 'Off'
                set(app.overlayfigure,'CloseRequestFcn','closereq');
                close(app.overlayfigure);
            end
        end

        % Button pushed function: SavePostureButton
        function SavePostureButtonPushed(app, event)
            frame = getsnapshot(app.vid);
            stoppreview(app.vid);
            
            uisave('frame','posture');
            app.SavePostureButton.Text = 'Saved';
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            if app.previewactive == false
                app.previewactive=true;
                app.previewfigure = figure('numbertitle', 'off', ...
                                           'Name','Previewer', ...
                                           'MenuBar', 'none', ...
                                           'ToolBar', 'none');
                set(app.previewfigure,'CloseRequestFcn',[]);
                figure(app.previewfigure);
                hImage = image(zeros(1024,1280,1));
                preview(app.vid, hImage);
            end
        end

        % Button pushed function: StartVideoAcquisitionButton
        function StartVideoAcquisitionButtonPushed(app, event)
            if app.acquisitionactive == false
                app.acquisitionactive = true;
                app.vid.TriggerRepeat = Inf;
                app.vid.LoggingMode = 'disk';
                baseName = datestr(datetime('now'),'yyyymmdd');
                baseName = strcat('FOB_',baseName,'_',app.FlyRepIDEditField.Value,'.avi');
                folder = uigetdir();
                app.logfilename = strcat(folder,'\',baseName);
                logfile = VideoWriter(app.logfilename, 'Grayscale AVI');
                app.vid.DiskLogger = logfile;
                start(app.vid)
                app.StartVideoAcquisitionButton.Text = 'Recording...';
            end
        end

        % Button pushed function: StopPreviewButton
        function StopPreviewButtonPushed(app, event)
            if app.previewactive == true
                app.previewactive=false;
                stoppreview(app.vid);
                set(app.previewfigure,'CloseRequestFcn','closereq');
                close(app.previewfigure);
            end
        end

        % Button pushed function: StopVideoAcquisitionButton
        function StopVideoAcquisitionButtonPushed(app, event)
            if app.acquisitionactive == true
                app.acquisitionactive = false;
                stop(app.vid);
                app.StartVideoAcquisitionButton.Text = 'Start Video Acquisition';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlyViewerUIFigure = uifigure;
            app.FlyViewerUIFigure.Name = 'FlyViewer';
            app.FlyViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @FlyViewerUIFigureCloseRequest, true);
            app.StartVideoAcquisitionButton = uibutton(app.FlyViewerUIFigure, 'push');
            app.StartVideoAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideoAcquisitionButtonPushed, true);
            app.StartVideoAcquisitionButton.Text = 'Start  Video Acquisition';
            app.SavePostureButton = uibutton(app.FlyViewerUIFigure, 'push');
            app.SavePostureButton.ButtonPushedFcn = createCallbackFcn(app, @SavePostureButtonPushed, true);
            app.SavePostureButton.Text = 'Save Posture';
            app.AssignPostureButton = uibutton(app.FlyViewerUIFigure, 'push');
            app.AssignPostureButton.ButtonPushedFcn = createCallbackFcn(app, @AssignPostureButtonPushed, true);
            app.AssignPostureButton.Text = 'Assign Posture';
            app.OverlayPostureSwitchLabel = uilabel(app.FlyViewerUIFigure);
            app.OverlayPostureSwitchLabel.Text = 'Overlay Posture';
            app.OverlayPostureSwitch = uiswitch(app.FlyViewerUIFigure, 'slider');
            app.OverlayPostureSwitch.ValueChangedFcn = createCallbackFcn(app, @OverlayPostureSwitchValueChanged, true);
            app.StopVideoAcquisitionButton = uibutton(app.FlyViewerUIFigure, 'push');
            app.StopVideoAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StopVideoAcquisitionButtonPushed, true);
            app.StopVideoAcquisitionButton.Text = 'Stop  Video Acquisition';
            app.StartPreviewButton = uibutton(app.FlyViewerUIFigure, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start  Preview';
            app.StopPreviewButton = uibutton(app.FlyViewerUIFigure, 'push');
            app.StopPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StopPreviewButtonPushed, true);
            app.StopPreviewButton.Text = 'Stop  Preview';
            app.FlyViewer2019TurnerLabHHMILabel = uilabel(app.FlyViewerUIFigure);
            app.FlyViewer2019TurnerLabHHMILabel.Text = 'FlyViewer2019@TurnerLabHHMI';
            app.Label = uilabel(app.FlyViewerUIFigure);
            app.Label.Text = '';
            app.FlyRepIDEditFieldLabel = uilabel(app.FlyViewerUIFigure);
            app.FlyRepIDEditFieldLabel.Text = 'FlyRep ID';
            app.FlyRepIDEditField = uieditfield(app.FlyViewerUIFigure, 'text');
            app.FlyRepIDEditField.Value = 'DefaultFly';
            app.CloseButton = uibutton(app.FlyViewerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = videoapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlyViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlyViewerUIFigure)
        end
    end
end